classdef LinkBudgetCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LinkBudgetCalculatorMATLABUIFigure  matlab.ui.Figure
        StatusLabel                     matlab.ui.control.Label
        RxSensitivityEditField          matlab.ui.control.NumericEditField
        ReceiverSensitivitydBmEditFieldLabel  matlab.ui.control.Label
        ResultsTextArea                 matlab.ui.control.TextArea
        ResultsLabel                    matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        DistanceEditField               matlab.ui.control.NumericEditField
        DistancekmEditFieldLabel        matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        FrequencyGHzEditFieldLabel      matlab.ui.control.Label
        TxGainEditField                 matlab.ui.control.NumericEditField
        TxAntennaGaindBiEditFieldLabel  matlab.ui.control.Label
        RxGainEditField                 matlab.ui.control.NumericEditField
        RxAntennaGaindBiEditFieldLabel  matlab.ui.control.Label
        TxPowerEditField                matlab.ui.control.NumericEditField
        TransmitPowerdBmEditFieldLabel  matlab.ui.control.Label
        LinkBudgetCalculatorLabel       matlab.ui.control.Label
        UIAxesLink                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            try

                % ----------------------------------------------------
                % LEER LOS DATOS INTRODUCIDOS POR EL USUARIO
                % ----------------------------------------------------

                % Potencia transmitida (dBm)
                Pt = app.TxPowerEditField.Value;

                % Ganancia de la antena transmisora (dBi)
                Gt = app.TxGainEditField.Value;

                % Ganancia de la antena receptora (dBi)
                Gr = app.RxGainEditField.Value;

                % Frecuencia de operación (GHz)
                f = app.FrequencyEditField.Value;

                % Distancia entre transmisor y receptor (km)
                d = app.DistanceEditField.Value;

                RxSens = app.RxSensitivityEditField.Value;

    % ----------------------------------------------------
    % CÁLCULO DE LA PÉRDIDA EN ESPACIO LIBRE (FSPL)
    % ----------------------------------------------------
    %
    % Fórmula:
    %
    % FSPL(dB) = 92.45 + 20·log10(f_GHz) + 20·log10(d_km)
    %
    % Cuanto mayor sea la distancia o la frecuencia,
    % mayores serán las pérdidas.
    %
    % ----------------------------------------------------

                FSPL = 92.45 + 20*log10(f) + 20*log10(d);


                % ----------------------------------------------------
                % CÁLCULO DE LA POTENCIA RECIBIDA
                % ----------------------------------------------------
                %
                % Ecuación básica del Link Budget:
                %
                % Pr = Pt + Gt + Gr - FSPL
                %
                % donde:
                % Pt   -> Potencia transmitida
                % Gt   -> Ganancia antena TX
                % Gr   -> Ganancia antena RX
                % FSPL -> Pérdidas del enlace
                %
                % ----------------------------------------------------

                Pr = Pt + Gt + Gr - FSPL;
                
                % Margen del enlace
                LinkMargin = Pr - RxSens;


                % Distancia máxima teórica del enlace
                FSPLmax = Pt + Gt + Gr - RxSens;

                dmax = 10^((FSPLmax - 92.45 - 20*log10(f))/20);
                
                if LinkMargin > 0

                    estado = "LINK ESTABLISHED";

                else

                    estado = "LINK NOT VIABLE";

                end

                app.StatusLabel.Text = estado;

                if LinkMargin > 0

                    app.StatusLabel.FontColor = [0 0.6 0];

                else

                    app.StatusLabel.FontColor = [1 0 0];

                end

                % ----------------------------------------------------
                % CONSTRUIR EL TEXTO DE SALIDA
                % ----------------------------------------------------

                resultado = ...
                    "LINK BUDGET RESULTS" + newline + ...
                    "---------------------" + newline + ...
                    "FSPL: " + string(round(FSPL,2)) + " dB" + newline + ...
                    "Received Power: " + string(round(Pr,2)) + " dBm" + newline + ...
                    "Receiver Sensitivity: " + string(RxSens) + " dBm" + newline + ...
                    "Maximum Distance: " + string(round(dmax,1)) + " km" + newline + ...
                    "Link Margin: " + string(round(LinkMargin,2)) + " dB";

                % ----------------------------------------------------
                % MOSTRAR RESULTADOS EN LA APP
                % ----------------------------------------------------

                app.ResultsTextArea.Value = splitlines(resultado);
                % ----------------------------------------------------
                % GRÁFICA POTENCIA RECIBIDA vs DISTANCIA
                % ----------------------------------------------------

                % Vector de distancias desde 1 km hasta la distancia introducida
                d_vec = linspace(1,dmax,500);

                % FSPL para cada distancia
                FSPL_vec = 92.45 + 20*log10(f) + 20*log10(d_vec);

                % Potencia recibida para cada distancia
                Pr_vec = Pt + Gt + Gr - FSPL_vec;

                % Dibujar gráfica
                % Limpiar eje
                cla(app.UIAxesLink)

                % Curva de potencia recibida
                plot(app.UIAxesLink,d_vec,Pr_vec,'LineWidth',2)

                hold(app.UIAxesLink,'on')

                % Línea de sensibilidad
                yline(app.UIAxesLink,RxSens,'--','Receiver Sensitivity')

                hold(app.UIAxesLink,'off')

                legend(app.UIAxesLink,...
                'Received Power',...
                'Receiver Sensitivity',...
                'Location','best')

                grid(app.UIAxesLink,'on')

                xlabel(app.UIAxesLink,'Distance (km)')
                ylabel(app.UIAxesLink,'Received Power (dBm)')
                title(app.UIAxesLink,'Received Power vs Distance')
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LinkBudgetCalculatorMATLABUIFigure = uifigure('Visible', 'off');
            app.LinkBudgetCalculatorMATLABUIFigure.Name = 'Link-Budget-Calculator-MATLAB';
            app.UIAxesLink = uiaxes(app.LinkBudgetCalculatorMATLABUIFigure);
            title(app.UIAxesLink, 'Potencia Recibida vs Distancia')
            xlabel(app.UIAxesLink, 'Distancia (km)')
            ylabel(app.UIAxesLink, 'Potencia Recibida (dBm) ')
            zlabel(app.UIAxesLink, 'Z')
            app.LinkBudgetCalculatorLabel = uilabel(app.LinkBudgetCalculatorMATLABUIFigure);
            app.LinkBudgetCalculatorLabel.Text = 'Link Budget Calculator';
            app.TransmitPowerdBmEditFieldLabel = uilabel(app.LinkBudgetCalculatorMATLABUIFigure);
            app.TransmitPowerdBmEditFieldLabel.Text = 'Transmit Power (dBm)';
            app.TxPowerEditField = uieditfield(app.LinkBudgetCalculatorMATLABUIFigure, 'numeric');
            app.RxAntennaGaindBiEditFieldLabel = uilabel(app.LinkBudgetCalculatorMATLABUIFigure);
            app.RxAntennaGaindBiEditFieldLabel.Text = 'Rx Antenna Gain (dBi)';
            app.RxGainEditField = uieditfield(app.LinkBudgetCalculatorMATLABUIFigure, 'numeric');
            app.TxAntennaGaindBiEditFieldLabel = uilabel(app.LinkBudgetCalculatorMATLABUIFigure);
            app.TxAntennaGaindBiEditFieldLabel.Text = 'Tx Antenna Gain (dBi)';
            app.TxGainEditField = uieditfield(app.LinkBudgetCalculatorMATLABUIFigure, 'numeric');
            app.FrequencyGHzEditFieldLabel = uilabel(app.LinkBudgetCalculatorMATLABUIFigure);
            app.FrequencyGHzEditFieldLabel.Text = 'Frequency (GHz)';
            app.FrequencyEditField = uieditfield(app.LinkBudgetCalculatorMATLABUIFigure, 'numeric');
            app.DistancekmEditFieldLabel = uilabel(app.LinkBudgetCalculatorMATLABUIFigure);
            app.DistancekmEditFieldLabel.Text = 'Distance (km)';
            app.DistanceEditField = uieditfield(app.LinkBudgetCalculatorMATLABUIFigure, 'numeric');
            app.CalculateButton = uibutton(app.LinkBudgetCalculatorMATLABUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResultsLabel = uilabel(app.LinkBudgetCalculatorMATLABUIFigure);
            app.ResultsLabel.Text = 'Results';
            app.ResultsTextArea = uitextarea(app.LinkBudgetCalculatorMATLABUIFigure);
            app.ReceiverSensitivitydBmEditFieldLabel = uilabel(app.LinkBudgetCalculatorMATLABUIFigure);
            app.ReceiverSensitivitydBmEditFieldLabel.Text = 'Receiver Sensitivity (dBm)';
            app.RxSensitivityEditField = uieditfield(app.LinkBudgetCalculatorMATLABUIFigure, 'numeric');
            app.RxSensitivityEditField.Value = -90;
            app.StatusLabel = uilabel(app.LinkBudgetCalculatorMATLABUIFigure);
            app.StatusLabel.Text = 'SYSTEM STATUS';
            % Show the figure after all components are created
            app.LinkBudgetCalculatorMATLABUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LinkBudgetCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LinkBudgetCalculatorMATLABUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LinkBudgetCalculatorMATLABUIFigure)
        end
    end
end